classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BuyButton                      matlab.ui.control.Button
        CompanyEditFieldLabel          matlab.ui.control.Label
        CompanyEditField               matlab.ui.control.EditField
        QuantityEditFieldLabel         matlab.ui.control.Label
        QuantityEditField              matlab.ui.control.NumericEditField
        LimitPriceEditFieldLabel       matlab.ui.control.Label
        LimitPriceEditField            matlab.ui.control.NumericEditField
        AccountNumberEditFieldLabel    matlab.ui.control.Label
        AccountNumberEditField         matlab.ui.control.EditField
        MovingAvgDropDownLabel         matlab.ui.control.Label
        MovingAvgDropDown              matlab.ui.control.DropDown
        SamsBadaTraderLabel            matlab.ui.control.Label
        Copyright2019YourFaceIncLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        params % Description
    end
    
    properties (Access = public)
        allTrades = []; % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %WHEN DEBUGGING, COMMENT OUT THIS CODE
            setGlobalT([]);
            %This creates a timer object that calls a function at a fixed rate specified by the period
            t = timer('StartDelay', 0, 'Period', 5, 'ExecutionMode', 'fixedRate','TimerFcn',@myFun);
            start(t);
            
            %This is the function
            function myFun(obj, event)
                %This is passing the newest version of the allTrades object to the AWS function.
                app.allTrades = AWS(app.allTrades);
            end
            %WHEN DEBUGGING UNCOMMENT THIS CODE
%               while 1 ~= 0
%                   pause(5);
%                   app.allTrades = AWS(app.allTrades);
%               end
        end

        % Button pushed function: BuyButton
        function BuyButtonPushed(app, event)
            %This is loading all the relevant trade data  from the gui into a parameters object
            app.params.Action = 'buy';
            app.params.Symbol = app.CompanyEditField.Value;
            app.params.Quantity = app.QuantityEditField.Value;
            app.params.LimitPrice = app.LimitPriceEditField.Value;
            app.params.Type = 'MTL';
            app.params.AccountName = app.AccountNumberEditField.Value;
            
            %Creates new trade stucture with with the trades parameters and the moving average that has been selected
            newTrade = trade;
            newTrade.paramas = app.params;
            newTrade.movingAvg = app.MovingAvgDropDown.Value;
            
            %Adds the new trade structure to the alltrades vector
            app.allTrades = [app.allTrades, newTrade];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %deletes all timer object and closes app
            tms = timerfindall;
            delete(tms);
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BuyButton = uibutton(app.UIFigure, 'push');
            app.BuyButton.ButtonPushedFcn = createCallbackFcn(app, @BuyButtonPushed, true);
            app.BuyButton.Text = 'Buy';
            app.CompanyEditFieldLabel = uilabel(app.UIFigure);
            app.CompanyEditFieldLabel.Text = 'Company';
            app.CompanyEditField = uieditfield(app.UIFigure, 'text');
            app.QuantityEditFieldLabel = uilabel(app.UIFigure);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.QuantityEditField = uieditfield(app.UIFigure, 'numeric');
            app.LimitPriceEditFieldLabel = uilabel(app.UIFigure);
            app.LimitPriceEditFieldLabel.Text = 'Limit Price';
            app.LimitPriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccountNumberEditFieldLabel = uilabel(app.UIFigure);
            app.AccountNumberEditFieldLabel.Text = 'Account Number';
            app.AccountNumberEditField = uieditfield(app.UIFigure, 'text');
            app.MovingAvgDropDownLabel = uilabel(app.UIFigure);
            app.MovingAvgDropDownLabel.Text = 'Moving Avg';
            app.MovingAvgDropDown = uidropdown(app.UIFigure);
            app.MovingAvgDropDown.Items = {'1 min', '5 min', '1 hour', '1 day', '1W'};
            app.MovingAvgDropDown.Value = '1 min';
            app.SamsBadaTraderLabel = uilabel(app.UIFigure);
            app.SamsBadaTraderLabel.Text = 'Sam''s Bada$$ Trader';
            app.Copyright2019YourFaceIncLabel = uilabel(app.UIFigure);
            app.Copyright2019YourFaceIncLabel.Text = 'Copyright 2019. Your Face Inc.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end